classdef ROIBuilder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ROIAxes                       matlab.ui.control.UIAxes
        Panel                         matlab.ui.container.Panel
        SelectHeadModelButton         matlab.ui.control.Button
        HeadModelPathText             matlab.ui.control.EditField
        SelectParcellation            matlab.ui.control.Button
        ParcellationText              matlab.ui.control.EditField
        SelectExistingROIButton       matlab.ui.control.Button
        CurrentROIText                matlab.ui.control.EditField
        ROIBuilderLabel               matlab.ui.control.Label
        ParcelselectorPanel           matlab.ui.container.Panel
        ParcelsincludedTextAreaLabel  matlab.ui.control.Label
        ParcelsincludedTextArea       matlab.ui.control.TextArea
        ParcelsexcludedTextAreaLabel  matlab.ui.control.Label
        ParcelsexcludedTextArea       matlab.ui.control.TextArea
        BrainpainterPanel             matlab.ui.container.Panel
        SliderLabel                   matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        SaveROIButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        repoPath = fileparts(mfilename('fullpath'));
        pathnameHeadModel;
        pathnameROI;
        ROI = struct('gmNodeList',[],'scalpPosList',[]);
    end
    
    
    methods (Access = private)
        
        function startupFcn(app, pathnameROI, pathnameHeadModel)
            if exist(pathnameROI,'var')
                if ~isempty(pathnameROI)
                    app.pathnameROI = pathnameROI;
                end
            end
            if exist(pathnameHeadModel,'var')
                if ~isempty(pathnameHeadModel)
                    app.pathnameHeadModel = pathnameHeadModel;
                end
            end
        end
        
        
        function results = updateFigures(app)
            
            %Update ROI plot
            cla(app.ROIAxes);
            if ~isempty(app.pathnameROI) && ~isempty(app.pathnameHeadModel)
                plotROI(app.pathnameHeadModel,app.pathnameROI,'hAxes',app.ROIAxes);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectHeadModelButton
        function SelectHeadModelButtonPushed(app, event)
            [filename, pathname, ~] = uigetfile([app.repoPath '/*.mshs'],'Select .mshs file');
            fullpath = [pathname filename];
            [pathname,filename,ext] = fileparts(fullpath);
            pathnameHeadModeltmp = [pathname '/' filename];
            mshstmp = load([pathnameHeadModeltmp ext],'-mat');
            
            %Check if there is a folder pwd/HeadModels/filename. If not,
            %create and save mshs there
            if ~exist([app.repoPath '/HeadModels/' filename],'dir')
               mkdir([app.repoPath '/HeadModels/' filename]);
               save(pathnameHeadModeltmp,'-struct','mshstmp');
               app.pathnameHeadModel = [app.repoPath '/HeadModels/' filename];
           
            %If headmodel exists with this name, but specified path is different. Warn and overwrite
            elseif ~strcmpi([app.repoPath '/HeadModels/' filename '/' filename],pathnameHeadModeltmp)  
                selection = uiconfirm(app.UIFigure,'A head model with this name already exists in ArrayDesigner','Head Model','Options',{'Use Existing','Overwrite Existing'},'DefaultOption',1,...
                        'Icon','warning');
                    
                    if strcmpi(selection,'Use Existing')
                        app.pathnameHeadModel = [app.repoPath '/HeadModels/' filename];

                    elseif strcmpi(selection,'Overwrite Existing')
                        save([app.repoPath '/HeadModels/' filename ext],'-struct','mshstmp');
                        app.pathnameHeadModel = [app.repoPath '/HeadModels/' filename];
                    end
            else %Headmodel is existing one selected from HeadModels folder
                %Specify pathnameHeadModel as that selected
                app.pathnameHeadModel = [app.repoPath '/HeadModels/' filename];
            end
                         
            %Update text field
            app.HeadModelPathText.Value = [filename ext];
            
            %Clear ROI text field
            app.CurrentROIText.Value = '';
            app.pathnameROI = '';
            app.ROI = struct('gmNodeList',[],'scalpPosList',[]);
            
            %Update axes
            updateFigures(app);
           
        end

        % Button pushed function: SelectExistingROIButton
        function SelectExistingROIButtonPushed(app, event)
            [filename, pathname, ~] = uigetfile([app.repoPath '/*.txt'],'Select ROI text file');
            fullpath = [pathname filename];
            [pathname,filename,ext] = fileparts(fullpath);
            app.pathnameROI = [pathname '/' filename ext];
            
            app.CurrentROIText.Value = [filename ext];
            
            %Update axes
            updateFigures(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ROIAxes = uiaxes(app.UIFigure);
            title(app.ROIAxes, '')
            xlabel(app.ROIAxes, 'X (mm)')
            ylabel(app.ROIAxes, 'Y (mm)')
            app.Panel = uipanel(app.UIFigure);
            app.SelectHeadModelButton = uibutton(app.Panel, 'push');
            app.SelectHeadModelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectHeadModelButtonPushed, true);
            app.SelectHeadModelButton.Text = 'Select Head Model';
            app.HeadModelPathText = uieditfield(app.Panel, 'text');
            app.HeadModelPathText.Editable = 'off';
            app.SelectParcellation = uibutton(app.Panel, 'push');
            app.SelectParcellation.Text = 'Select Parcellation';
            app.ParcellationText = uieditfield(app.Panel, 'text');
            app.ParcellationText.Editable = 'off';
            app.SelectExistingROIButton = uibutton(app.Panel, 'push');
            app.SelectExistingROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExistingROIButtonPushed, true);
            app.SelectExistingROIButton.Text = 'Select Existing ROI';
            app.CurrentROIText = uieditfield(app.Panel, 'text');
            app.CurrentROIText.Editable = 'off';
            app.ROIBuilderLabel = uilabel(app.UIFigure);
            app.ROIBuilderLabel.Text = 'ROI Builder';
            app.ParcelselectorPanel = uipanel(app.UIFigure);
            app.ParcelselectorPanel.Title = 'Parcel selector';
            app.ParcelsincludedTextAreaLabel = uilabel(app.ParcelselectorPanel);
            app.ParcelsincludedTextAreaLabel.Text = 'Parcels (included)';
            app.ParcelsincludedTextArea = uitextarea(app.ParcelselectorPanel);
            app.ParcelsexcludedTextAreaLabel = uilabel(app.ParcelselectorPanel);
            app.ParcelsexcludedTextAreaLabel.Text = 'Parcels (excluded)';
            app.ParcelsexcludedTextArea = uitextarea(app.ParcelselectorPanel);
            app.BrainpainterPanel = uipanel(app.UIFigure);
            app.BrainpainterPanel.Title = 'Brain painter';
            app.SliderLabel = uilabel(app.BrainpainterPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.BrainpainterPanel);
            app.SaveROIButton = uibutton(app.UIFigure, 'push');
            app.SaveROIButton.Text = 'Save ROI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIBuilder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end